classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        s1TechLaucherMenu      matlab.ui.container.Menu
        Menu_3                 matlab.ui.container.Menu
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        plotButton             matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        LogPrintTextArea       matlab.ui.control.TextArea
        LogPrintTextAreaLabel  matlab.ui.control.Label
        loadButton             matlab.ui.control.Button
        SelectButton           matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        EditFieldLabel         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        StrArray % Description
        ExcelData % Description
    end
    
    methods (Access = private)
        
        function logRefresh_func(app,StrArrayNew)
            if length(app.StrArray)>=1
                app.StrArray=[];
            end
            app.StrArray=[app.StrArray,StrArrayNew];
            app.LogPrintTextArea.Value=app.StrArray;

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function ButtonPushed(app, event)
           
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.EditField.Value=value;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed2(app, event)
            [filename,pathname]=uigetfile('*.xlsx');
           if ~isequal(filename,0)
               app.EditField.Value=fullfile(pathname,filename);
               StrArrayNew=('select success');
               logRefresh_func(app,StrArrayNew);
           else
               StrArrayNew=('select fail');
               logRefresh_func(app,StrArrayNew);
               return
           end
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            try
            % 从 Excel 中读取数据
            app.ExcelData = readtable(app.EditField.Value); % 替换成你的 Excel 文件路径

            % 将数据显示在表格中
            app.UITable.Data = app.ExcelData;
            StrArrayNew=('load success');
               logRefresh_func(app,StrArrayNew);
            catch
                StrArrayNew=('load fail');
               logRefresh_func(app,StrArrayNew);
               return

            end
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            % get data
            data = app.UITable.Data;
            
            % x for X, y for Y
            x = data.Var1;
            y = data.Var2;
   
            
            % plot(x,y);
            plot(app.UIAxes,x,y,'-ro');
            
            % set lable
            title(app.UIAxes, 'Data Plot');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.s1TechLaucherMenu = uimenu(app.UIFigure);
            app.s1TechLaucherMenu.Text = '2024s1-TechLaucher';
            app.Menu_3 = uimenu(app.s1TechLaucherMenu);
            app.Menu_3.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.Value = '/Users/louiswang/2024s1/techLauncher/appdesigner/jerry.bmp';
            app.SelectButton = uibutton(app.Tab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed2, true);
            app.SelectButton.Text = 'Select';
            app.loadButton = uibutton(app.Tab, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.LogPrintTextAreaLabel = uilabel(app.Tab);
            app.LogPrintTextAreaLabel.Text = 'Log Print';
            app.LogPrintTextArea = uitextarea(app.Tab);
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'column1'; 'column2'};
            app.UITable.RowName = {};
            app.plotButton = uibutton(app.Tab, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Tab.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end